classdef HydroScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MotorsTab                     matlab.ui.container.Tab
        Lamp_Motor                    matlab.ui.control.Lamp
        ConnectButton_Motor           matlab.ui.control.StateButton
        GohomeButton                  matlab.ui.control.Button
        EditField_zav                 matlab.ui.control.EditField
        EditField_yav                 matlab.ui.control.EditField
        EditField_xav                 matlab.ui.control.EditField
        EditField_zrv                 matlab.ui.control.EditField
        EditField_yrv                 matlab.ui.control.EditField
        EditField_xrv                 matlab.ui.control.EditField
        SethomeButton                 matlab.ui.control.Button
        ZButton_Minus                 matlab.ui.control.Button
        ZButton_Plus                  matlab.ui.control.Button
        YButton_Minus                 matlab.ui.control.Button
        YButton_Plus                  matlab.ui.control.Button
        XButton_Minus                 matlab.ui.control.Button
        XButton_Plus                  matlab.ui.control.Button
        PicoTab                       matlab.ui.container.Tab
        Lamp_Pico                     matlab.ui.control.Lamp
        ConnectButton_Pico            matlab.ui.control.StateButton
        PulseButton                   matlab.ui.control.StateButton
        PulsePanel                    matlab.ui.container.Panel
        NumberofcyclesEditField       matlab.ui.control.EditField
        NumberofcyclesEditFieldLabel  matlab.ui.control.Label
        FrequencyMHzEditField         matlab.ui.control.EditField
        FrequencyMHzEditFieldLabel    matlab.ui.control.Label
        UIAxes_Pico                   matlab.ui.control.UIAxes
        ScanTab                       matlab.ui.container.Tab
    end


    properties (Access = private)
        ps5000aDeviceObj % Description
        Motor % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ConnectButton_Pico
        function ConnectButton_PicoValueChanged(app, event)
            value = app.ConnectButton_Pico.Value;
            if value
                h = instrfind('Type','scope');
                if ~isempty(h)
                    disconnect(h);
                    delete(h);
                end
                PS5000aConfig;
                if (exist('app.ps5000aDeviceObj', 'var') && app.ps5000aDeviceObj.isvalid && strcmp(app.ps5000aDeviceObj.status, 'open'))
                    disconnect(app.ps5000aDeviceObj);
                    delete(app.ps5000aDeviceObj);
                end
                app.ps5000aDeviceObj = icdevice('picotech_ps5000a_generic.mdd');
                connect(app.ps5000aDeviceObj);
                %%
                %% exist('app.ps5000aDeviceObj', 'var') does not work
                %%
                if (exist('app.ps5000aDeviceObj', 'var') && app.ps5000aDeviceObj.isvalid && strcmp(app.ps5000aDeviceObj.status, 'open'))
                    app.Lamp_Pico.Color = [0 1 0];
                end
            else
                disconnect(app.ps5000aDeviceObj);
                delete(app.ps5000aDeviceObj);
                app.Lamp_Pico.Color = [1 0 0];
            end
        end

        % Value changed function: ConnectButton_Motor
        function ConnectButton_MotorValueChanged(app, event)
            value = app.ConnectButton_Motor.Value;
            if value
                app.Motor = MotorXYZT;
                app.Motor.connect('XXX');
                app.Lamp_Motor.Color = [0 1 0];
            else
                app.Motor.disconnect();
                app.Lamp_Motor.Color = [1 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MotorsTab = uitab(app.TabGroup);
            app.MotorsTab.Title = 'Motors';
            app.XButton_Plus = uibutton(app.MotorsTab, 'push');
            app.XButton_Plus.Tag = 'xp';
            app.XButton_Plus.Text = 'X+';
            app.XButton_Minus = uibutton(app.MotorsTab, 'push');
            app.XButton_Minus.Tag = 'xm';
            app.XButton_Minus.Text = 'X-';
            app.YButton_Plus = uibutton(app.MotorsTab, 'push');
            app.YButton_Plus.Tag = 'yp';
            app.YButton_Plus.Text = 'Y+';
            app.YButton_Minus = uibutton(app.MotorsTab, 'push');
            app.YButton_Minus.Tag = 'ym';
            app.YButton_Minus.Text = 'Y-';
            app.ZButton_Plus = uibutton(app.MotorsTab, 'push');
            app.ZButton_Plus.Tag = 'zp';
            app.ZButton_Plus.Text = 'Z+';
            app.ZButton_Minus = uibutton(app.MotorsTab, 'push');
            app.ZButton_Minus.Tag = 'zm';
            app.ZButton_Minus.Text = 'Z-';
            app.SethomeButton = uibutton(app.MotorsTab, 'push');
            app.SethomeButton.Text = 'Set home';
            app.EditField_xrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_xrv.Tag = 'xrv';
            app.EditField_xrv.Value = '0.0';
            app.EditField_yrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_yrv.Tag = 'yrv';
            app.EditField_yrv.Value = '0.0';
            app.EditField_zrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_zrv.Tag = 'zrv';
            app.EditField_zrv.Value = '0.0';
            app.EditField_xav = uieditfield(app.MotorsTab, 'text');
            app.EditField_xav.Tag = 'xav';
            app.EditField_xav.Editable = 'off';
            app.EditField_xav.Enable = 'off';
            app.EditField_xav.Value = '0.0';
            app.EditField_yav = uieditfield(app.MotorsTab, 'text');
            app.EditField_yav.Tag = 'yav';
            app.EditField_yav.Editable = 'off';
            app.EditField_yav.Enable = 'off';
            app.EditField_yav.Value = '0.0';
            app.EditField_zav = uieditfield(app.MotorsTab, 'text');
            app.EditField_zav.Tag = 'zav';
            app.EditField_zav.Editable = 'off';
            app.EditField_zav.Enable = 'off';
            app.EditField_zav.Value = '0.0';
            app.GohomeButton = uibutton(app.MotorsTab, 'push');
            app.GohomeButton.Text = 'Go home';
            app.ConnectButton_Motor = uibutton(app.MotorsTab, 'state');
            app.ConnectButton_Motor.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_MotorValueChanged, true);
            app.ConnectButton_Motor.Text = 'Connect';
            app.Lamp_Motor = uilamp(app.MotorsTab);
            app.PicoTab = uitab(app.TabGroup);
            app.PicoTab.Title = 'Pico';
            app.UIAxes_Pico = uiaxes(app.PicoTab);
            xlabel(app.UIAxes_Pico, 'X')
            ylabel(app.UIAxes_Pico, 'Y')
            zlabel(app.UIAxes_Pico, 'Z')
            app.PulsePanel = uipanel(app.PicoTab);
            app.PulsePanel.Title = 'Pulse';
            app.FrequencyMHzEditFieldLabel = uilabel(app.PulsePanel);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzEditField = uieditfield(app.PulsePanel, 'text');
            app.FrequencyMHzEditField.Value = '10.00';
            app.NumberofcyclesEditFieldLabel = uilabel(app.PulsePanel);
            app.NumberofcyclesEditFieldLabel.Text = 'Number of cycles';
            app.NumberofcyclesEditField = uieditfield(app.PulsePanel, 'text');
            app.NumberofcyclesEditField.Value = '10.00';
            app.PulseButton = uibutton(app.PicoTab, 'state');
            app.PulseButton.Text = 'Pulse';
            app.ConnectButton_Pico = uibutton(app.PicoTab, 'state');
            app.ConnectButton_Pico.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_PicoValueChanged, true);
            app.ConnectButton_Pico.Text = 'Connect';
            app.Lamp_Pico = uilamp(app.PicoTab);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HydroScan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end